classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_2  matlab.ui.control.NumericEditField
        Label_4      matlab.ui.control.Label
        DropDown     matlab.ui.control.DropDown
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.EditField
        Label_3      matlab.ui.control.Label
        Lamp         matlab.ui.control.Lamp
        LampLabel    matlab.ui.control.Label
        rc9cpprobotadjustsystemtestLabel  matlab.ui.control.Label
        Button       matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        availablePorts_info
        availablecom
        serial_obj
        data 
        float_values
        txs
        mys
        mys2
        mys3
        p
        p2
        p3
        x
        i
        ext
        disp_datay
        disp_datay2
        disp_datay3
    end
    
    methods (Access = public)
        
        % 回调函数：当接收到1个字节时执行
    function serialCallback(app, src, ~)
        % 定义协议常量
        FRAME_HEAD_0 = 0xFC;
        FRAME_HEAD_1 = 0xFB;
        FRAME_END_0 = 0xFD;
        FRAME_END_1 = 0xFE;
        MAX_DATA_LENGTH = 64;
        
        persistent state rxBuffer dataBuffer rxIndex data_length frame_id;  % 使用持久变量保存状态机的状态
        if isempty(state)
            state = 'WAITING_FOR_HEADER_0';  % 初始化状态
            rxBuffer = [];
            dataBuffer = [];
            rxIndex = 0;
            data_length = 0;
            frame_id = 0;
        end
        
        % 读取串口接收到的字节数据
        byte = read(src, 1, "uint8");  % 读取1个字节的数据
        
        % 显示接收到的字节
        %disp(['Received byte: ', num2str(byte)]);
    
        % 状态机解析数据
        switch state
            case 'WAITING_FOR_HEADER_0'
                if byte == FRAME_HEAD_0
                    state = 'WAITING_FOR_HEADER_1';
                    rxBuffer = [byte];  % 存储帧头
                end
                
            case 'WAITING_FOR_HEADER_1'
                if byte == FRAME_HEAD_1
                    state = 'WAITING_FOR_ID';
                    rxBuffer = [rxBuffer, byte];
                else
                    state = 'WAITING_FOR_HEADER_0';
                end
                
            case 'WAITING_FOR_ID'
                frame_id = byte;  % 帧ID
                state = 'WAITING_FOR_LENGTH';
                rxBuffer = [rxBuffer, byte];
                
            case 'WAITING_FOR_LENGTH'
                data_length = byte;  % 数据长度
                rxBuffer = [rxBuffer, byte];
                if data_length > MAX_DATA_LENGTH
                    disp('数据长度超出最大限制');
                    state = 'WAITING_FOR_HEADER_0';
                else
                    state = 'WAITING_FOR_DATA';
                    dataBuffer = [];  % 清空数据区
                    rxIndex = 0;
                end
                
            case 'WAITING_FOR_DATA'
                dataBuffer = [dataBuffer, byte];  % 存储接收到的数据
                rxIndex = rxIndex + 1;
                if rxIndex >= data_length
                    state = 'WAITING_FOR_CRC_0';
                end
                
            case 'WAITING_FOR_CRC_0'
                %crc_high = byte;  % CRC 高字节
                state = 'WAITING_FOR_CRC_1';
               
                
            case 'WAITING_FOR_CRC_1'
                %crc_low = byte;  % CRC 低字节
                %received_crc = bitor(bitshift(crc_high, 8), crc_low);
                
                
                state = 'WAITING_FOR_END_0';


            case 'WAITING_FOR_END_0'
                if byte == FRAME_END_0
                    state = 'WAITING_FOR_END_1';
                else
                    state = 'WAITING_FOR_HEADER_0';
                end
                
            case 'WAITING_FOR_END_1'
                if byte == FRAME_END_1
                    %disp('成功接收到数据帧');
                    % 将数据更新到 UI 控件
                    %app.EditField_2.Value = dataBuffer(1);  % 假设数据为 uint8，更新显示
                    app.float_values = typecast(uint8(dataBuffer), 'single');  % 将数据转换为 float
                    %disp(app.float_values);
                    app.disp_datay=app.float_values(1);
                     app.disp_datay2=app.float_values(2);
                      app.disp_datay3=app.float_values(3);
                    % 处理数据帧
                    %processReceivedData(app, frame_id, dataBuffer);
                   
                end
                state = 'WAITING_FOR_HEADER_0';
                
            otherwise
                state = 'WAITING_FOR_HEADER_0';
        end
    end

        
        function updatePlot(app)
    % 检查 XData 和 YData 长度是否一致
                    %disp("timer!");
                     %disp(app.float_values);
                    app.txs = [app.txs app.txs(end) + 0.01];
                    app.mys=[app.mys app.disp_datay]; 
                     app.mys2 = [app.mys2 app.disp_datay2];
                    app.mys3 = [app.mys3 app.disp_datay3];

                    if length(app.txs) == length(app.mys) && length(app.txs) == length(app.mys2) && length(app.txs) == length(app.mys3)
                       % 更新三条曲线的数据
                        set(app.p, 'XData', app.txs, 'YData', app.mys);    % 更新第一条曲线
                        set(app.p2, 'XData', app.txs, 'YData', app.mys2);  % 更新第二条曲线
                        set(app.p3, 'XData', app.txs, 'YData', app.mys3);  % 更新第三条曲线
                        drawnow;
                    else
                        disp('XData 和 YData 长度不一致！');
                         disp(['XData 长度: ', num2str(length(app.txs)), ', YData 长度: ', num2str(length(app.mys))]);
                        
                    end
                    
                    app.x=app.x+0.01;    
                    axis([app.x app.x+1 -460 460]);
                    %pause(0.01);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.availablePorts_info=get_port();
               
               app.DropDown.Items= app.availablePorts_info;
                app.txs=[0];
                app.mys=[0];
                app.mys2=[0];
                app.mys3=[0];
                
                %app.p = plot(app.txs,app.mys,'g','MarkerSize',5);%LineSpec 应该作为第三个位置的字符串输入
               % 绘制三条曲线
                    hold on;
                app.p = plot( app.txs, app.mys, 'g', 'MarkerSize', 5);  % 第一条绿色曲线
                app.p2 = plot(app.txs, app.mys2, 'b', 'MarkerSize', 5); % 第二条蓝色曲线
                app.p3 = plot( app.txs, app.mys3, 'r', 'MarkerSize', 5); % 第三条红色曲线
                 hold off;

                
                app.x=-1;
                axis([app.x app.x+1 -460 460]);
                grid on;
                % 设置背景颜色为黑色
                set(gca, 'Color', 'k');  % 'k' 表示黑色
                set(gca, 'GridColor', 'w');  % 设置网格线颜色为白色
                app.disp_datay=0;
                app.disp_datay2=0;
                app.disp_datay3=0;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
              
             app.availablecom=extractBetween(app.DropDown.Value, "(", ")");
           try
               app.serial_obj= serialport(char(app.availablecom), 115200);
               configureCallback( app.serial_obj, "byte", 1, @app.serialCallback);
               app.EditField.Value="连接到"+string(app.availablecom);
               % 创建定时器对象
                app.ext = timer(...
                    'ExecutionMode', 'fixedRate', ...  % 固定间隔执行
                    'Period', 0.001, ...  % 每 0.1 秒更新一次
                    'TimerFcn', @(~,~)app.updatePlot() ...  % 定时器触发时调用 updatePlot 函数
                );
                 start(app.ext);
           catch
                app.EditField.Value="无法连接到"+string(app.availablecom);

           end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
           
          
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
            
               
        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '连接';
            app.rc9cpprobotadjustsystemtestLabel = uilabel(app.UIFigure);
            app.rc9cpprobotadjustsystemtestLabel.Tag = 'lab_1';
            app.rc9cpprobotadjustsystemtestLabel.Text = 'rc9cpp robot adjust system test';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '连接状态';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = '未连接';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '串口端口';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = {};
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '接收到的字节';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end